classdef VisualizeAntennaCoverage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        DirectivityPlot                 matlab.ui.control.UIAxes
        MapPanel                        matlab.ui.container.Panel
        SwarmParametersPanel            matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        DirectionalAntennaTab           matlab.ui.container.Tab
        TiltAxisDropDown                matlab.ui.control.DropDown
        TiltAxisDropDownLabel           matlab.ui.control.Label
        PlotDirectivityPatternButton    matlab.ui.control.Button
        TiltAngleEditField              matlab.ui.control.NumericEditField
        TiltAngleEditFieldLabel         matlab.ui.control.Label
        PlotIdealcoverageAreaButton     matlab.ui.control.Button
        PropagationModelDropDown        matlab.ui.control.DropDown
        PropagationModelDropDownLabel   matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        TransmitterTab_2                matlab.ui.container.Tab
        TransmitterPowerwattsEditField  matlab.ui.control.NumericEditField
        TransmitterPowerwattsEditFieldLabel  matlab.ui.control.Label
        AntennaHeightmetersEditField    matlab.ui.control.NumericEditField
        AntennaHeightmetersEditFieldLabel  matlab.ui.control.Label
        TransmitterFrequencye9HzEditField  matlab.ui.control.NumericEditField
        Frequencye9Label                matlab.ui.control.Label
        ReceiverTab_2                   matlab.ui.container.Tab
        ReceiverSentivityEditField      matlab.ui.control.NumericEditField
        ReceiverSentivityEditFieldLabel  matlab.ui.control.Label
        EnvironmentalPropertiesPanel    matlab.ui.container.Panel
        BaseMapDropDown                 matlab.ui.control.DropDown
        BaseMapDropDownLabel            matlab.ui.control.Label
        ReceiverSitesListBox            matlab.ui.control.ListBox
        ReceiverSitesListBoxLabel       matlab.ui.control.Label
        TransmitterSitesListBox         matlab.ui.control.ListBox
        TransmitterSitesListBoxLabel    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RegionofInterestROITab          matlab.ui.container.Tab
        SouthWestLongitudeEditField     matlab.ui.control.NumericEditField
        SouthWestLongitudeEditField_2Label  matlab.ui.control.Label
        NorthEastLongitudeEditField     matlab.ui.control.NumericEditField
        NorthEastLongitudeEditFieldLabel  matlab.ui.control.Label
        PlotROIButton                   matlab.ui.control.Button
        SouthWestLatitudeEditField      matlab.ui.control.NumericEditField
        SouthWestLongitudeLabel         matlab.ui.control.Label
        NorthEastLatitudeEditField      matlab.ui.control.NumericEditField
        NorthEastLatitudeEditFieldLabel  matlab.ui.control.Label
        TransmitterTab                  matlab.ui.container.Tab
        TransmitterAddSiteButton        matlab.ui.control.Button
        TransmitterNameofSiteEditField  matlab.ui.control.EditField
        NameofSiteEditFieldLabel        matlab.ui.control.Label
        TransmitterLongitudeEditField   matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        TransmiterLatitudeEditField     matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        ReceiverTab                     matlab.ui.container.Tab
        ReceiverAddSiteButton           matlab.ui.control.Button
        ReceiverNameofSiteEditField     matlab.ui.control.EditField
        NameofSiteEditField_2Label      matlab.ui.control.Label
        ReceiverLongitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        ReceiverLatitudeEditField       matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Latitudes = []% Description
        Longitudes = []% Description
        transmitterLat = []
        receiverLat = []
        transmitterLon = []
        receiverLon = []
        transmitters = []
        receivers = []
        geoPlotAxes
        txa
        rxsa
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitterAddSiteButton
        function TransmitterAddSiteButtonPushed(app, event)
            app.transmitters = [app.transmitters app.TransmitterNameofSiteEditField.Value];
            app.transmitterLat = [app.transmitterLat (app.TransmiterLatitudeEditField.Value)];
            app.transmitterLon = [app.transmitterLon (app.TransmitterLongitudeEditField.Value)];
            app.TransmitterSitesListBox.Items{end+1} = app.TransmitterNameofSiteEditField.Value;
            disp(app.TransmitterSitesListBox.Items)
            app.Latitudes = [app.Latitudes (app.TransmiterLatitudeEditField.Value)];
            app.Longitudes = [app.Longitudes (app.TransmitterLongitudeEditField.Value)];
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: ReceiverAddSiteButton
        function ReceiverAddSiteButtonPushed(app, event)
            app.receivers = [app.receivers app.ReceiverNameofSiteEditField.Value];
            app.receiverLat = [app.receiverLat app.ReceiverLatitudeEditField.Value];
            app.receiverLon = [app.receiverLon app.ReceiverLongitudeEditField.Value];
            app.ReceiverSitesListBox.Items{end+1} = app.ReceiverNameofSiteEditField.Value;
            app.Latitudes = [app.Latitudes (app.ReceiverLatitudeEditField.Value)];
            app.Longitudes = [app.Longitudes (app.ReceiverLongitudeEditField.Value)];
            
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: PlotIdealcoverageAreaButton
        function PlotIdealcoverageAreaButtonPushed(app, event)
            num = app.TransmitterFrequencye9HzEditField.Value;
            str = 'e9';
            fq = str2double([num2str(num) str]);
            disp((fq))
            tx = txsite("Name",(app.TransmitterNameofSiteEditField.Value), ...
                "Latitude",(app.TransmiterLatitudeEditField.Value), ...
                "Longitude",(app.TransmitterLongitudeEditField.Value), ...
                "Antenna",design(dipole,(fq)), ...
                "AntennaHeight",app.AntennaHeightmetersEditField.Value, ...        % Units: meters
                "TransmitterFrequency",fq, ... % Units: Hz
                "TransmitterPower",app.TransmitterPowerwattsEditField.Value);        % Units: Watts
            rxs = rxsite("Name",app.receivers, ...
                "Latitude",app.receiverLat, ...
                "Longitude",app.receiverLon, ...
                "Antenna",design(dipole,tx.TransmitterFrequency), ...
                "ReceiverSensitivity",app.ReceiverSentivityEditField.Value); % Units: dBm
            app.txa = tx;

            app.rxsa = rxs;

            viewer = siteviewer;
            show(tx)
            show(rxs)
            viewer.Basemap = "openstreetmap";

            coverage(tx,app.PropagationModelDropDown.Value, ...
                "SignalStrengths",app.ReceiverSentivityEditField.Value)
            sc = [0 0.3 0];
            link(rxs,tx,app.PropagationModelDropDown.Value,"SuccessColor",sc)
        end

        % Button pushed function: PlotDirectivityPatternButton
        function PlotDirectivityPatternButtonPushed(app, event)
            yagiAnt = design(yagiUda,app.txa.TransmitterFrequency);

            % Tilt antenna to direct radiation in XY-plane (i.e. geographic azimuth)
            disp(app.TiltAngleEditField.Value)
            disp(app.TiltAxisDropDown.Value)

            yagiAnt.Tilt = app.TiltAngleEditField.Value;
            yagiAnt.TiltAxis = app.TiltAxisDropDown.Value;
    
            f = figure;

            % Show directivity pattern
            patternAzimuth(yagiAnt,app.txa.TransmitterFrequency);

            % Get the content of the figure as an image
            figImage = getframe(f);
            imageData = figImage.cdata;

            % Display the image in the UIAxes
            imshow(imageData, 'Parent', app.DirectivityPlot);
            app.txa.Antenna = yagiAnt;

            % Point main beam toward Boston, MA by assigning azimuth angle between
            % transmitter location and Boston receiver location
            app.txa.AntennaAngle = angle(app.txa, app.rxsa(1));

            % Define signal strengths from sensitivity to -60 dB
            sigStrengths = app.ReceiverSentivityEditField.Value:5:-60;
            sc = [0 0.3 0];

            % Update coverage map
            coverage(app.txa,"rain","SignalStrengths",sigStrengths)

            link(app.rxsa,app.txa,"rain","SuccessColor",sc)
        end

        % Value changed function: BaseMapDropDown
        function BaseMapDropDownValueChanged(app, event)
            value = app.BaseMapDropDown.Value;
            
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, value)
        end

        % Button pushed function: PlotROIButton
        function PlotROIButtonPushed(app, event)
            app.TransmitterSitesListBox.Items = {};
            app.ReceiverSitesListBox.Items = {};
            app.transmitters = [];
            app.transmitterLat = [];
            app.transmitterLon = [];

            app.receivers = [];
            app.receiverLat = [];
            app.receiverLon = [];
            % Define the bounding box coordinates
            ne_latitude = app.SouthWestLatitudeEditField.Value;  % North-east latitude
            sw_longitude = app.NorthEastLongitudeEditField.Value;  % South-west longitude
            nw_latitude = app.NorthEastLatitudeEditField.Value;  % North-west latitude
            se_longitude = app.SouthWestLongitudeEditField.Value;  % South-east longitude

            % Plot the bounding box
            geoAxes = geoaxes(app.MapPanel);
            app.geoPlotAxes = geoAxes;
            geoplot(app.geoPlotAxes, [ne_latitude, ne_latitude, nw_latitude, nw_latitude, ne_latitude], [sw_longitude, se_longitude, se_longitude, sw_longitude, sw_longitude], 'g-.', 'LineWidth', 2);
            hold(app.geoPlotAxes, 'on')
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentalPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentalPropertiesPanel.Title = 'Environmental Properties';
            app.TabGroup = uitabgroup(app.EnvironmentalPropertiesPanel);
            app.RegionofInterestROITab = uitab(app.TabGroup);
            app.RegionofInterestROITab.Title = 'Region of Interest (ROI)';
            app.NorthEastLatitudeEditFieldLabel = uilabel(app.RegionofInterestROITab);
            app.NorthEastLatitudeEditFieldLabel.Text = 'North-East Latitude';
            app.NorthEastLatitudeEditField = uieditfield(app.RegionofInterestROITab, 'numeric');
            app.NorthEastLatitudeEditField.Value = 42.2;
            app.SouthWestLongitudeLabel = uilabel(app.RegionofInterestROITab);
            app.SouthWestLongitudeLabel.Text = 'South-West Latitude';
            app.SouthWestLatitudeEditField = uieditfield(app.RegionofInterestROITab, 'numeric');
            app.SouthWestLatitudeEditField.Value = 42.5;
            app.PlotROIButton = uibutton(app.RegionofInterestROITab, 'push');
            app.PlotROIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotROIButtonPushed, true);
            app.PlotROIButton.Text = 'Plot ROI';
            app.NorthEastLongitudeEditFieldLabel = uilabel(app.RegionofInterestROITab);
            app.NorthEastLongitudeEditFieldLabel.Text = 'North-East Longitude';
            app.NorthEastLongitudeEditField = uieditfield(app.RegionofInterestROITab, 'numeric');
            app.NorthEastLongitudeEditField.Value = -71;
            app.SouthWestLongitudeEditField_2Label = uilabel(app.RegionofInterestROITab);
            app.SouthWestLongitudeEditField_2Label.Text = 'South-West Longitude';
            app.SouthWestLongitudeEditField = uieditfield(app.RegionofInterestROITab, 'numeric');
            app.SouthWestLongitudeEditField.Value = -71.7;
            app.TransmitterTab = uitab(app.TabGroup);
            app.TransmitterTab.Title = 'Transmitter';
            app.LatitudeEditFieldLabel = uilabel(app.TransmitterTab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.TransmiterLatitudeEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.TransmiterLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmiterLatitudeEditField.Value = 42.3001;
            app.LongitudeEditFieldLabel = uilabel(app.TransmitterTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.TransmitterLongitudeEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.TransmitterLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmitterLongitudeEditField.Value = -71.3504;
            app.NameofSiteEditFieldLabel = uilabel(app.TransmitterTab);
            app.NameofSiteEditFieldLabel.Text = 'Name of Site';
            app.TransmitterNameofSiteEditField = uieditfield(app.TransmitterTab, 'text');
            app.TransmitterNameofSiteEditField.Value = 'MathWorks';
            app.TransmitterAddSiteButton = uibutton(app.TransmitterTab, 'push');
            app.TransmitterAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitterAddSiteButtonPushed, true);
            app.TransmitterAddSiteButton.Text = 'Add Site';
            app.ReceiverTab = uitab(app.TabGroup);
            app.ReceiverTab.Title = 'Receiver';
            app.LatitudeEditField_2Label = uilabel(app.ReceiverTab);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.ReceiverLatitudeEditField = uieditfield(app.ReceiverTab, 'numeric');
            app.ReceiverLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLatitudeEditField.Value = 42.3601;
            app.LongitudeEditField_2Label = uilabel(app.ReceiverTab);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.ReceiverLongitudeEditField = uieditfield(app.ReceiverTab, 'numeric');
            app.ReceiverLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLongitudeEditField.Value = -71.0589;
            app.NameofSiteEditField_2Label = uilabel(app.ReceiverTab);
            app.NameofSiteEditField_2Label.Text = 'Name of Site';
            app.ReceiverNameofSiteEditField = uieditfield(app.ReceiverTab, 'text');
            app.ReceiverNameofSiteEditField.Value = 'Boston';
            app.ReceiverAddSiteButton = uibutton(app.ReceiverTab, 'push');
            app.ReceiverAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverAddSiteButtonPushed, true);
            app.ReceiverAddSiteButton.Text = 'Add Site';
            app.TransmitterSitesListBoxLabel = uilabel(app.EnvironmentalPropertiesPanel);
            app.TransmitterSitesListBoxLabel.Text = {'Transmitter'; 'Sites'};
            app.TransmitterSitesListBox = uilistbox(app.EnvironmentalPropertiesPanel);
            app.TransmitterSitesListBox.Items = {};
            app.TransmitterSitesListBox.Value = {};
            app.ReceiverSitesListBoxLabel = uilabel(app.EnvironmentalPropertiesPanel);
            app.ReceiverSitesListBoxLabel.Text = {'Receiver'; 'Sites'};
            app.ReceiverSitesListBox = uilistbox(app.EnvironmentalPropertiesPanel);
            app.ReceiverSitesListBox.Items = {};
            app.ReceiverSitesListBox.Value = {};
            app.BaseMapDropDownLabel = uilabel(app.EnvironmentalPropertiesPanel);
            app.BaseMapDropDownLabel.Text = 'Base Map';
            app.BaseMapDropDown = uidropdown(app.EnvironmentalPropertiesPanel);
            app.BaseMapDropDown.Items = {'streets', 'darkwater', 'grayland', 'bluegreen', 'grayterrain', 'colorterrain', 'landcover', 'streets-light', 'streets-dark', 'satellite', 'topographic'};
            app.BaseMapDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseMapDropDownValueChanged, true);
            app.BaseMapDropDown.Value = 'streets';
            app.SwarmParametersPanel = uipanel(app.UIFigure);
            app.SwarmParametersPanel.Title = 'Swarm Parameters';
            app.TabGroup_2 = uitabgroup(app.SwarmParametersPanel);
            app.TransmitterTab_2 = uitab(app.TabGroup_2);
            app.TransmitterTab_2.Title = 'Transmitter';
            app.Frequencye9Label = uilabel(app.TransmitterTab_2);
            app.Frequencye9Label.Text = 'Frequency (*e9 Hz)';
            app.TransmitterFrequencye9HzEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterFrequencye9HzEditField.Value = 6;
            app.AntennaHeightmetersEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.AntennaHeightmetersEditFieldLabel.Text = 'Antenna Height (meters)';
            app.AntennaHeightmetersEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.AntennaHeightmetersEditField.Value = 60;
            app.TransmitterPowerwattsEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.TransmitterPowerwattsEditFieldLabel.Text = 'Transmitter Power (watts)';
            app.TransmitterPowerwattsEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterPowerwattsEditField.Value = 15;
            app.ReceiverTab_2 = uitab(app.TabGroup_2);
            app.ReceiverTab_2.Title = 'Receiver';
            app.ReceiverSentivityEditFieldLabel = uilabel(app.ReceiverTab_2);
            app.ReceiverSentivityEditFieldLabel.Text = 'Receiver Sentivity';
            app.ReceiverSentivityEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverSentivityEditField.Value = -90;
            app.PropagationModelDropDownLabel = uilabel(app.SwarmParametersPanel);
            app.PropagationModelDropDownLabel.Text = 'Propagation Model';
            app.PropagationModelDropDown = uidropdown(app.SwarmParametersPanel);
            app.PropagationModelDropDown.Items = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.ItemsData = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.Value = 'freespace';
            app.PlotIdealcoverageAreaButton = uibutton(app.SwarmParametersPanel, 'push');
            app.PlotIdealcoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIdealcoverageAreaButtonPushed, true);
            app.PlotIdealcoverageAreaButton.Text = 'Plot Ideal coverage Area';
            app.TabGroup2 = uitabgroup(app.SwarmParametersPanel);
            app.DirectionalAntennaTab = uitab(app.TabGroup2);
            app.DirectionalAntennaTab.Title = 'Directional Antenna';
            app.TiltAngleEditFieldLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle';
            app.TiltAngleEditField = uieditfield(app.DirectionalAntennaTab, 'numeric');
            app.TiltAngleEditField.Value = 90;
            app.PlotDirectivityPatternButton = uibutton(app.DirectionalAntennaTab, 'push');
            app.PlotDirectivityPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDirectivityPatternButtonPushed, true);
            app.PlotDirectivityPatternButton.Text = 'Plot Directivity Pattern';
            app.TiltAxisDropDownLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAxisDropDownLabel.Text = 'Tilt Axis';
            app.TiltAxisDropDown = uidropdown(app.DirectionalAntennaTab);
            app.TiltAxisDropDown.Items = {'x', 'y'};
            app.TiltAxisDropDown.Value = 'x';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DirectivityPlot = uiaxes(app.Panel);
            app.DirectivityPlot.XTick = [];
            app.DirectivityPlot.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeAntennaCoverage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end